classdef CrimeProjectcopy < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        DistrictKeyLabel                matlab.ui.control.Label
        TextArea                        matlab.ui.control.TextArea
        OverallDataPanel                matlab.ui.container.Panel
        SaveDistrictOffenseCountDatatoFileButton  matlab.ui.control.Button
        OccurrencesLabel_2              matlab.ui.control.Label
        OccurrencesLabel                matlab.ui.control.Label
        MostOffenseDistrictTextArea_4   matlab.ui.control.TextArea
        TextArea_2Label_4               matlab.ui.control.Label
        MostOffenseDistrictTextArea_3   matlab.ui.control.TextArea
        TextArea_2Label_3               matlab.ui.control.Label
        withLabel_2                     matlab.ui.control.Label
        withLabel                       matlab.ui.control.Label
        MostOffenseDistrictTextArea_2   matlab.ui.control.TextArea
        TextArea_2Label_2               matlab.ui.control.Label
        MostOffenseDistrictTextArea     matlab.ui.control.TextArea
        TextArea_2Label                 matlab.ui.control.Label
        FindDistrictwithLeastOffensesButton  matlab.ui.control.Button
        FindDistrictwithMostOffensesButton_2  matlab.ui.control.Button
        CriminalOffensesinBoston20152018Label  matlab.ui.control.Label
        OffensesbyDistrictPanel         matlab.ui.container.Panel
        OccurrenceAmountLabel           matlab.ui.control.Label
        DistrictCodeLabel               matlab.ui.control.Label
        TextArea2_6                     matlab.ui.control.TextArea
        TextArea2_5                     matlab.ui.control.TextArea
        TextArea2_4                     matlab.ui.control.TextArea
        Label_7                         matlab.ui.control.Label
        Label_6                         matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        TextArea2_3                     matlab.ui.control.TextArea
        TextArea2_2                     matlab.ui.control.TextArea
        Top3DistrictsinwhichOffenseisMostCommonLabel  matlab.ui.control.Label
        TextArea2                       matlab.ui.control.TextArea
        PlotOffenseDropDown             matlab.ui.control.DropDown
        PlotOffenseDropDownLabel        matlab.ui.control.Label
        PlotOffenseOccurrencesButton_2  matlab.ui.control.Button
        OffenseOccurenceinDistrctPanel  matlab.ui.container.Panel
        TextArea1_6                     matlab.ui.control.TextArea
        TextArea1_5                     matlab.ui.control.TextArea
        TextArea1_4                     matlab.ui.control.TextArea
        OccurrenceAmountLabel_2         matlab.ui.control.Label
        OffenseNameLabel                matlab.ui.control.Label
        TextArea1_2                     matlab.ui.control.TextArea
        TextArea1_3                     matlab.ui.control.TextArea
        Label_4                         matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        MostCommonOffensesinDistrictLabel  matlab.ui.control.Label
        TextArea1                       matlab.ui.control.TextArea
        PlotDistrictCodeDropDown        matlab.ui.control.DropDown
        PlotDistrictCodeDropDownLabel   matlab.ui.control.Label
        PlotOffenseOccurrencesButton    matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
    end


    properties (Access = public)
        Offense %Offense Data
        District %District Data
        AllCrimeData %Combined District and Offense Data
        CrimesList %List of crimes/offense type from the data file
        DistrictsList %List of districts from the data file
        DistrictOffenseCount %Number of occurrences of offense/crime from each district
        DistrictNames
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Reads from original data file and saves offense data and district data into text array called C
           
            [num,txt,raw] = xlsread('crime.xlsx');
            offense = txt(:,3); 
            offense(1) = [];
            offense2 = string(offense);
            district = txt(:,5);
            district(1) = [];
            district2 = string(district);
            C = [offense2, district2];
            app.Offense = offense2;
            app.District = district2;
            app.AllCrimeData = C;

            %Assigns the List of offenses and district names to app.CrimesList and app.DistrictsList
            app.CrimesList = ["Larceny" "Investigate Person" "Motor Vehicle Accident Response" "Aggravated Assault" "Harassment" "Medical Assistance" "Verbal Disputes" "Robbery" "Simple Assault" "Drug Violation" "Residential Burglary" "Vandalism" "Arson" "Fraud" "Homicide"];
            app.DistrictsList = ["A1" "A7" "A15" "B2" "B3" "C6" "C11" "D4" "D14" "E5" "E13" "E18"];
           
            
            %Calculates the number of occurrences of offenses from each district
            dc = [];
            for i = 1:length(app.DistrictsList)
                count = nnz(strcmp(app.District,app.DistrictsList(i)));
                dc = [dc count];
            end
            app.DistrictOffenseCount = dc;
            
            %Resizes the figure window to fit
            app.UIFigure.Position = [10 -300 1137 1505];
        end

        % Button pushed function: PlotOffenseOccurrencesButton
        function PlotOffenseOccurrencesButtonPushed(app, event)
            %Calculates the crime occurrences for specified district type
            value = app.PlotDistrictCodeDropDown.Value;
            DistrictCrimeData = app.AllCrimeData(find(strcmp(app.District,value)),1);
            y = [];
            for i = 1:length(app.CrimesList)
                offensecount = nnz(strcmp(DistrictCrimeData,app.CrimesList(i)));
                y = [y offensecount];
            end
            [B I] = maxk(y,3);
           
            %Plots the bar graph for crime occurrences in district
            bar(app.UIAxes,y);
            xtickangle(app.UIAxes,45);
            set(app.UIAxes,'xticklabel',app.CrimesList);
            title(app.UIAxes,['Offenses in District ',value]);
            
            %Outputs the top 3 offenses and their occurrence count in district
            app.TextArea1.Value = app.CrimesList(I(1));
            app.TextArea1_4.Value = int2str(B(1));
            app.TextArea1_2.Value = app.CrimesList(I(2));
            app.TextArea1_5.Value = int2str(B(2));
            app.TextArea1_3.Value = app.CrimesList(I(3));
            app.TextArea1_6.Value = int2str(B(3));
        end

        % Button pushed function: PlotOffenseOccurrencesButton_2
        function PlotOffenseOccurrencesButton_2Pushed(app, event)
            %Calculates the crime occurrences for specified crime
            value = app.PlotOffenseDropDown.Value;
            OffenseCrimeData = app.AllCrimeData(find(strcmp(app.Offense,value)),2);
            y = [];
            
            for i = 1:length(app.DistrictsList)
                districtcount = nnz(strcmp(OffenseCrimeData,app.DistrictsList(i)));
                y = [y districtcount];
            end
            [B I] = maxk(y,3);
            
            %Plots the bar graph for crime occurences per crime
            bar(app.UIAxes2,y);
            set(app.UIAxes2,'xticklabel',app.DistrictsList);
            title(app.UIAxes2,[value,' by District']);
            
            %Outputs the top 3 districts with their crime occurrence
            app.TextArea2.Value = app.DistrictsList(I(1));
            app.TextArea2_4.Value = int2str(B(1));
            app.TextArea2_2.Value = app.DistrictsList(I(2));
            app.TextArea2_5.Value = int2str(B(2));
            app.TextArea2_3.Value = app.DistrictsList(I(3));
            app.TextArea2_6.Value = int2str(B(3));
        end

        % Button pushed function: 
        % FindDistrictwithMostOffensesButton_2
        function FindDistrictwithMostOffensesButton_2Pushed(app, event)
            %Outputs the most common crime/offense and number of occurrences
            [B I] = max(app.DistrictOffenseCount);
            app.MostOffenseDistrictTextArea_2.Value = app.DistrictsList(I);
            app.MostOffenseDistrictTextArea_3.Value = int2str(B);
            
        end

        % Button pushed function: 
        % FindDistrictwithLeastOffensesButton
        function FindDistrictwithLeastOffensesButtonPushed(app, event)
            %Outputs the least common crime/offense and number of occurrences
            [B I] = min(app.DistrictOffenseCount);
            app.MostOffenseDistrictTextArea.Value = app.DistrictsList(I);
            app.MostOffenseDistrictTextArea_4.Value = int2str(B);
            
        end

        % Button pushed function: 
        % SaveDistrictOffenseCountDatatoFileButton
        function SaveDistrictOffenseCountDatatoFileButtonPushed(app, event)
            %Gets offense occurrence data per district and saves it to doc.dat file
            fid = fopen('doc.dat','w');
            fprintf(fid,'%s %s %s %s %s %s %s %s %s %s %s %s\n',app.DistrictsList);
            fprintf(fid,'%d %d %d %d %d %d %d %d %d %d %d %d\n',app.DistrictOffenseCount);
            
            fclose(fid);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Offenses by District')
            xlabel(app.UIAxes2, 'District Code')
            ylabel(app.UIAxes2, 'Occurrence')
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Offenses in District')
            xlabel(app.UIAxes, 'Offense')
            ylabel(app.UIAxes, 'Occurrence')
            app.OffenseOccurenceinDistrctPanel = uipanel(app.UIFigure);
            app.OffenseOccurenceinDistrctPanel.Title = 'Offense Occurence in Distrct';
            app.PlotOffenseOccurrencesButton = uibutton(app.OffenseOccurenceinDistrctPanel, 'push');
            app.PlotOffenseOccurrencesButton.ButtonPushedFcn = createCallbackFcn(app, @PlotOffenseOccurrencesButtonPushed, true);
            app.PlotOffenseOccurrencesButton.Text = 'Plot Offense Occurrences';
            app.PlotDistrictCodeDropDownLabel = uilabel(app.OffenseOccurenceinDistrctPanel);
            app.PlotDistrictCodeDropDownLabel.Text = 'Plot District Code';
            app.PlotDistrictCodeDropDown = uidropdown(app.OffenseOccurenceinDistrctPanel);
            app.PlotDistrictCodeDropDown.Items = {'Choose District', 'A1', 'A7', 'A15', 'B2', 'B3', 'C6', 'C11', 'D4', 'D14', 'E5', 'E13', 'E18'};
            app.PlotDistrictCodeDropDown.Value = 'Choose District';
            app.TextArea1 = uitextarea(app.OffenseOccurenceinDistrctPanel);
            app.TextArea1.Editable = 'off';
            app.MostCommonOffensesinDistrictLabel = uilabel(app.OffenseOccurenceinDistrctPanel);
            app.MostCommonOffensesinDistrictLabel.Text = '3 Most Common Offenses in District';
            app.Label_2 = uilabel(app.OffenseOccurenceinDistrctPanel);
            app.Label_2.Text = '#1';
            app.Label_3 = uilabel(app.OffenseOccurenceinDistrctPanel);
            app.Label_3.Text = '#2';
            app.Label_4 = uilabel(app.OffenseOccurenceinDistrctPanel);
            app.Label_4.Text = '#3';
            app.TextArea1_3 = uitextarea(app.OffenseOccurenceinDistrctPanel);
            app.TextArea1_3.Editable = 'off';
            app.TextArea1_2 = uitextarea(app.OffenseOccurenceinDistrctPanel);
            app.TextArea1_2.Editable = 'off';
            app.OffenseNameLabel = uilabel(app.OffenseOccurenceinDistrctPanel);
            app.OffenseNameLabel.Text = 'Offense Name';
            app.OccurrenceAmountLabel_2 = uilabel(app.OffenseOccurenceinDistrctPanel);
            app.OccurrenceAmountLabel_2.Text = 'Occurrence Amount';
            app.TextArea1_4 = uitextarea(app.OffenseOccurenceinDistrctPanel);
            app.TextArea1_4.Editable = 'off';
            app.TextArea1_5 = uitextarea(app.OffenseOccurenceinDistrctPanel);
            app.TextArea1_5.Editable = 'off';
            app.TextArea1_6 = uitextarea(app.OffenseOccurenceinDistrctPanel);
            app.TextArea1_6.Editable = 'off';
            app.OffensesbyDistrictPanel = uipanel(app.UIFigure);
            app.OffensesbyDistrictPanel.Title = 'Offenses by District';
            app.PlotOffenseOccurrencesButton_2 = uibutton(app.OffensesbyDistrictPanel, 'push');
            app.PlotOffenseOccurrencesButton_2.ButtonPushedFcn = createCallbackFcn(app, @PlotOffenseOccurrencesButton_2Pushed, true);
            app.PlotOffenseOccurrencesButton_2.Text = {'Plot Offense Occurrences'; ''};
            app.PlotOffenseDropDownLabel = uilabel(app.OffensesbyDistrictPanel);
            app.PlotOffenseDropDownLabel.Text = 'Plot Offense';
            app.PlotOffenseDropDown = uidropdown(app.OffensesbyDistrictPanel);
            app.PlotOffenseDropDown.Items = {'Choose Offense', 'Larceny', 'Investigate Person', 'Motor Vehicle Accident Response', 'Aggravated Assault', 'Harassment', 'Medical Assistance', 'Verbal Disputes', 'Robbery', 'Simple Assault', 'Drug Violation', 'Residential Burglary', 'Vandalism', 'Arson', 'Fraud', 'Homicide'};
            app.PlotOffenseDropDown.Value = 'Choose Offense';
            app.TextArea2 = uitextarea(app.OffensesbyDistrictPanel);
            app.TextArea2.Editable = 'off';
            app.Top3DistrictsinwhichOffenseisMostCommonLabel = uilabel(app.OffensesbyDistrictPanel);
            app.Top3DistrictsinwhichOffenseisMostCommonLabel.Text = 'Top 3 Districts in which Offense is Most Common';
            app.TextArea2_2 = uitextarea(app.OffensesbyDistrictPanel);
            app.TextArea2_2.Editable = 'off';
            app.TextArea2_3 = uitextarea(app.OffensesbyDistrictPanel);
            app.TextArea2_3.Editable = 'off';
            app.Label_5 = uilabel(app.OffensesbyDistrictPanel);
            app.Label_5.Text = '#1';
            app.Label_6 = uilabel(app.OffensesbyDistrictPanel);
            app.Label_6.Text = '#2';
            app.Label_7 = uilabel(app.OffensesbyDistrictPanel);
            app.Label_7.Text = '#3';
            app.TextArea2_4 = uitextarea(app.OffensesbyDistrictPanel);
            app.TextArea2_4.Editable = 'off';
            app.TextArea2_5 = uitextarea(app.OffensesbyDistrictPanel);
            app.TextArea2_5.Editable = 'off';
            app.TextArea2_6 = uitextarea(app.OffensesbyDistrictPanel);
            app.TextArea2_6.Editable = 'off';
            app.DistrictCodeLabel = uilabel(app.OffensesbyDistrictPanel);
            app.DistrictCodeLabel.Text = 'District Code';
            app.OccurrenceAmountLabel = uilabel(app.OffensesbyDistrictPanel);
            app.OccurrenceAmountLabel.Text = 'Occurrence Amount';
            app.CriminalOffensesinBoston20152018Label = uilabel(app.UIFigure);
            app.CriminalOffensesinBoston20152018Label.Text = 'Criminal Offenses in Boston (2015 - 2018)';
            app.OverallDataPanel = uipanel(app.UIFigure);
            app.OverallDataPanel.Title = 'Overall Data';
            app.FindDistrictwithMostOffensesButton_2 = uibutton(app.OverallDataPanel, 'push');
            app.FindDistrictwithMostOffensesButton_2.ButtonPushedFcn = createCallbackFcn(app, @FindDistrictwithMostOffensesButton_2Pushed, true);
            app.FindDistrictwithMostOffensesButton_2.Text = {'Find District with Most Offenses'; ''};
            app.FindDistrictwithLeastOffensesButton = uibutton(app.OverallDataPanel, 'push');
            app.FindDistrictwithLeastOffensesButton.ButtonPushedFcn = createCallbackFcn(app, @FindDistrictwithLeastOffensesButtonPushed, true);
            app.FindDistrictwithLeastOffensesButton.Text = {'Find District with Least Offenses'; ''};
            app.TextArea_2Label = uilabel(app.OverallDataPanel);
            app.TextArea_2Label.Text = '';
            app.MostOffenseDistrictTextArea = uitextarea(app.OverallDataPanel);
            app.MostOffenseDistrictTextArea.Editable = 'off';
            app.TextArea_2Label_2 = uilabel(app.OverallDataPanel);
            app.TextArea_2Label_2.Text = '';
            app.MostOffenseDistrictTextArea_2 = uitextarea(app.OverallDataPanel);
            app.MostOffenseDistrictTextArea_2.Editable = 'off';
            app.withLabel = uilabel(app.OverallDataPanel);
            app.withLabel.Text = 'with';
            app.withLabel_2 = uilabel(app.OverallDataPanel);
            app.withLabel_2.Text = 'with';
            app.TextArea_2Label_3 = uilabel(app.OverallDataPanel);
            app.TextArea_2Label_3.Text = '';
            app.MostOffenseDistrictTextArea_3 = uitextarea(app.OverallDataPanel);
            app.MostOffenseDistrictTextArea_3.Editable = 'off';
            app.TextArea_2Label_4 = uilabel(app.OverallDataPanel);
            app.TextArea_2Label_4.Text = '';
            app.MostOffenseDistrictTextArea_4 = uitextarea(app.OverallDataPanel);
            app.MostOffenseDistrictTextArea_4.Editable = 'off';
            app.OccurrencesLabel = uilabel(app.OverallDataPanel);
            app.OccurrencesLabel.Text = 'Occurrences';
            app.OccurrencesLabel_2 = uilabel(app.OverallDataPanel);
            app.OccurrencesLabel_2.Text = 'Occurrences';
            app.SaveDistrictOffenseCountDatatoFileButton = uibutton(app.OverallDataPanel, 'push');
            app.SaveDistrictOffenseCountDatatoFileButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDistrictOffenseCountDatatoFileButtonPushed, true);
            app.SaveDistrictOffenseCountDatatoFileButton.Text = 'Save District Offense Count Data to File';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'A1 - Downtown A7 - East Boston'; 'A15 - Charlestown B2 - Roxbury'; 'B3 - Mattapan C6 - South Boston'; 'C11 - Dorchester D4 - South End'; 'D14 - Brighton E5 - West Roxbury'; 'E13 - Jamaica Plain E-18 Hyde Park'; ''};
            app.DistrictKeyLabel = uilabel(app.UIFigure);
            app.DistrictKeyLabel.Text = 'District Key';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CrimeProjectcopy

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end